classdef with_encoder_motion_control_experiment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ArmSpeedMonitorPanel            matlab.ui.container.Panel
        CurrentArmMovementNALabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        StimulusDirectionPracticePanel  matlab.ui.container.Panel
        PracticeStimDirectionButton     matlab.ui.control.Button
        AngleToTestEditField            matlab.ui.control.NumericEditField
        AngleToTestEditFieldLabel       matlab.ui.control.Label
        LinearStageSpeedTrainingPanel   matlab.ui.container.Panel
        RunTrainingTrialsButton         matlab.ui.control.Button
        SelectDirectionDropDown         matlab.ui.control.DropDown
        SelectDirectionDropDownLabel    matlab.ui.control.Label
        SelectSpeedDropDown             matlab.ui.control.DropDown
        SelectSpeedDropDownLabel        matlab.ui.control.Label
        TrainSingleSpeedButton          matlab.ui.control.Button
        ExperimentControlPanel          matlab.ui.container.Panel
        LoadPairedPassiveBlockButton    matlab.ui.control.Button
        LoadExperimentFileButton        matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.StateButton
        RunButton                       matlab.ui.control.StateButton
        MotorControlPanel               matlab.ui.container.Panel
        LinearStageMotorSwitch          matlab.ui.control.Switch
        LinearStageMotorSwitchLabel     matlab.ui.control.Label
        TestEncoderButton               matlab.ui.control.Button
        LoadMotorsCheckBox              matlab.ui.control.CheckBox
        CreateTrialsButton              matlab.ui.control.StateButton
        TrialhistoryTextArea            matlab.ui.control.TextArea
        TrialhistoryTextAreaLabel       matlab.ui.control.Label
        ExperimentFileNALabel           matlab.ui.control.Label
        GeneratetrialsButtonGroup       matlab.ui.container.ButtonGroup
        SpeedButton                     matlab.ui.control.RadioButton
        DirectionButton                 matlab.ui.control.RadioButton
        CentermotorsPanel               matlab.ui.container.Panel
        MovefullyupButton               matlab.ui.control.StateButton
        SetzeropointButton              matlab.ui.control.StateButton
        Set90Button                     matlab.ui.control.StateButton
        IndentEditField                 matlab.ui.control.NumericEditField
        IndentEditFieldLabel            matlab.ui.control.Label
        MoveUpDownButton                matlab.ui.control.StateButton
        RotatemotorButton               matlab.ui.control.StateButton
        RotationEditField               matlab.ui.control.NumericEditField
        RotationEditFieldLabel          matlab.ui.control.Label
        ParticipantsIDEditField         matlab.ui.control.EditField
        ParticipantsIDEditFieldLabel    matlab.ui.control.Label
        MovestagePanel                  matlab.ui.container.Panel
        SetstartPosButton               matlab.ui.control.StateButton
        MoveButton                      matlab.ui.control.StateButton
        DirectionButtonGroup            matlab.ui.container.ButtonGroup
        RightButton                     matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        StepsEditField                  matlab.ui.control.NumericEditField
        StepsEditFieldLabel             matlab.ui.control.Label
        SpeedEditField                  matlab.ui.control.NumericEditField
        SpeedEditFieldLabel             matlab.ui.control.Label
    end


    properties (Access = public) %(Access = private)
        fastSpeedCommanded = ExpConfig.fast_target_speed_steps;
        slowSpeedCommanded = ExpConfig.slow_target_speed_steps;
        moveDurSec = ExpConfig.StageMotionDur_sec;
        stimLagSec = ExpConfig.stimLag_sec;
        betweenTrialsMotionSpeed_steps = ExpConfig.betweenTrialsMotionSpeed_steps;
        betweenTrialsMotionAcc_steps = ExpConfig.betweenTrialsMotionAcc_steps;
        encoderThreshold_cm = ExpConfig.encoderThreshold_cm; % how far should the arm move to consider it motion

        %----------------------
        maxSpinRate = 1023;
        maxSpin_perDeg = 361.4;
        baseActuatorPos = 900;
        maxActuatorPos = 2100;
        maxActuatorDisp = 26; %in mm

        %----------------------
        motorsLoaded = 0;
        fileLoaded = [];
        curFolder = [];
        participantID = [];
        filesFolder = [];
        fileName = [];
        cam
        outputVideo
        vidname
        videoFolder
        curPairId = 0

        %----------------------
        %variables controlling arduino devices
        rotation_and_spin_motor = [];
        indentationActuator = [];
        LinearStage_motor = [];
        rotaryEncoder = [];
        MeasuredSpeedHistory = [];


        rotation_and_spin_motor_comPort = 'COM4'; %uno arduino
        indentationActuator_comPort = 'COM10'; %leo arduino
        LinearStage_motor_comPort = 'COM7';
        rotaryEncoder_comPort = 'COM5'; % example

        rotation_and_spin_motor_Rate = 115200;
        indentationActuator_Rate = 9600;
        LinearStage_motor_Rate = 115200;
        rotaryEncoder_Rate = 115200;
        %----------------------

        mycurLoc = [];
        mycurStartLoc = [];
        indentZero = [];
        rotationCtrAngle = [];
        curIndent = [];
        curAngle = 0;
        newAngle = nan;
        stopGUI = [];
    end

    methods (Access = private)
        function myTrialHistory = printResponseInfo(app, response,myTrialHistory)
            if response == 1
                myText = '  Left Response';
            elseif response == 3
                myText = '  Right Response';
            else
                myText = '  N/A';
            end
            myTrialHistory{end+1,1} = myText;
            app.TrialhistoryTextArea.Value = myTrialHistory;
            scroll(app.TrialhistoryTextArea, 'bottom');
            drawnow
        end


        function myTrialHistory = printTrialInfo(app, myTrialHistory, n, trialRow, myAngle)
            speedStage = trialRow.Arm_Mov_Speed_arduino;
            stepsStage = trialRow.Arm_Mov_Steps_arduino;
            [moveDir, speedStr, no_motion_flag]  = getTrialInfo(app, stepsStage, speedStage);
            
            myText = ['#' num2str(n) ' Arm moves ' moveDir ' with ' speedStr ' speed and wheel angle ' ...
                num2str(myAngle) 'º'];
            myTrialHistory{end+1,1} = myText;
            app.TrialhistoryTextArea.Value = myTrialHistory;
            scroll(app.TrialhistoryTextArea, 'bottom');
            drawnow
        end

        function [saveDir, outName] = getBehaviorSavePath(app)
            saveDir = fullfile(app.curFolder, [app.participantID '_data']);
            if ~isfolder(saveDir)
                mkdir(saveDir);
            end
            [~, baseName, ext] = fileparts(app.fileName);

            if isempty(ext)
                ext = '.mat';
            end

            behaviorFileName = [baseName '_behavior' ext];
            outName = fullfile(saveDir, behaviorFileName);
        end


        function ok = validateExperimentStart(app)
            ok = false;
            if ~app.motorsLoaded
                uialert(app.UIFigure, 'Motors have not been loaded', 'Error starting experiment');
            elseif isempty(app.fileLoaded)
                uialert(app.UIFigure, 'Experimental file is not loaded', 'Error starting experiment');
            elseif isempty(app.participantID)
                uialert(app.UIFigure, 'Participant ID is not set', 'Error starting experiment');
            else
                ok = true;
            end
        end


        %For motion dir exp
        function runInterTrialInterval(app, interTrialInterval, outName, experimentTrial_Matrix)
            curClock = GetSecs;
            dataSaved = 0;
            while GetSecs - curClock < interTrialInterval
                if dataSaved == 0
                    save(outName, 'experimentTrial_Matrix')
                   %  experimentTrial_Matrix.InterTrialInterval = interTrialInterval; 
                    %overwright iti for behavior out
                    dataSaved = 1;
                end
                drawnow limitrate
                if app.stopGUI == 1
                    errordlg('Experiment stopped', 'Stopped Exp')
                    app.stopGUI = 0;
                    error('Experiment stopped');
                end
            end
        end
        
        function updateMeasuredSpeedHistogram(app)
            vals = app.MeasuredSpeedHistory;
            vals = vals(isfinite(vals));   % guards against NaN, Inf, -Inf
            cla(app.UIAxes);
            if isempty(vals)
                title(app.UIAxes, 'No valid measured speeds');
                xlabel(app.UIAxes, 'Measured speed (cm/s)');
                ylabel(app.UIAxes, 'Count');
                return
            end
            histogram(app.UIAxes, vals, ...
                'FaceColor', [0.2 0.5 0.8], ...
                'EdgeColor', 'none');

            xlabel(app.UIAxes, 'Measured speed (cm/s)');
            ylabel(app.UIAxes, 'Count');
            title(app.UIAxes, sprintf('Measured speeds, n = %d', numel(vals)));
            grid(app.UIAxes, 'on');

            drawnow limitrate
        end
        function initHist(app)
            app.MeasuredSpeedHistory = [];
            cla(app.UIAxes)
            title(app.UIAxes, 'Measured speed histogram')
            xlabel(app.UIAxes, 'Measured speed (cm/s)')
            ylabel(app.UIAxes, 'Count')
            drawnow
        end 
        function waitReadyClick(app, windowPtr)
            % Wait for any held buttons to release first
            while true
                [~, ~, buttons] = GetMouse(windowPtr);
                if ~any(buttons), break; end
                drawnow limitrate
                if app.stopGUI == 1
                    app.stopGUI = 0;
                    error('Experiment stopped');
                end
            end
            % Wait for a new click
            while true
                [~, ~, buttons] = GetMouse(windowPtr);
                if any(buttons), break; end
                drawnow limitrate
                if app.stopGUI == 1
                    app.stopGUI = 0;
                    error('Experiment stopped');
                end
            end
            % Show "Wait..." and wait for release
            DrawFormattedText(windowPtr, '', 'center', 'center', [255 255 255]);
            Screen('Flip', windowPtr);
            while true
                [~, ~, buttons] = GetMouse(windowPtr);
                if ~any(buttons), break; end
                drawnow limitrate
                if app.stopGUI == 1
                    app.stopGUI = 0;
                    error('Experiment stopped');
                end
            end
        end

        function results = motionDirectionExperiment(app, windowPtr)
            app.participantID = app.ParticipantsIDEditField.Value;
            results = [];
            app.TrialhistoryTextArea.Value = "";
            myTrialHistory = {};
            initHist(app);
            if ~validateExperimentStart(app)
                return;
            end

            %% Load the block
            experimentTrial_Matrix = app.fileLoaded.TrialStim_param;
            nTrials = size(experimentTrial_Matrix, 1);
            app.curPairId = experimentTrial_Matrix.PairID(1);
            isActive = experimentTrial_Matrix.IsActive(1) == 1;

            % Make directory to save behavior files
            [saveDir, outName] = getBehaviorSavePath(app);

            % Setting to move the stage to first start position
            accStage = app.betweenTrialsMotionSpeed_steps ;
            cmd = sprintf("A %d\n", accStage);
            writeline(app.LinearStage_motor, cmd);
            pause(.1);

            speedStage = app.betweenTrialsMotionAcc_steps;
            cmd = sprintf("S %d\n", speedStage);
            writeline(app.LinearStage_motor, cmd);
            pause(.1);

            %% DISPLAY block instructions for 3 sec
            Screen('TextFont', windowPtr, 'Arial');
            Screen('TextSize', windowPtr, 28);
            Screen('TextStyle', windowPtr, 0);
            textString = ['Judge if stimulus moves left or right relative to your trunk' newline newline newline 'Experiment will begin in 3 seconds'];
            DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
            Screen('Flip', windowPtr);

            %Run trials (assumed no mixed blocks)
            expTime_start = GetSecs;
            %% Moves the stage to first start position of the block
            if isActive
                writeline(app.LinearStage_motor, "E 0");
                % Find first trial with actual movement
                firstMovingIdx = find(experimentTrial_Matrix.Arm_Mov_Steps_arduino ~= 0, 1, 'first');

                if isempty(firstMovingIdx)
                    % All trials are no-motion — park at 0
                    blockStartPositionSteps = 0;
                elseif experimentTrial_Matrix.Arm_mov_StartPosition_arduino(firstMovingIdx) < 0
                    blockStartPositionSteps = -ExpConfig.LinStage_range_steps;
                else
                    blockStartPositionSteps = 0;
                end

                cmd = sprintf("M %d\n", blockStartPositionSteps);
                writeline(app.LinearStage_motor, cmd);
                pause(1.5);
                app.mycurStartLoc = blockStartPositionSteps;
                writeline(app.LinearStage_motor, "E 1"); % sets the motor free
                pause(0.2);

            else
                writeline(app.LinearStage_motor, "E 0");
                blockStartPositionSteps = experimentTrial_Matrix.Arm_mov_StartPosition_arduino(1);
                cmd = sprintf("M %d\n", blockStartPositionSteps);
                writeline(app.LinearStage_motor, cmd);
                pause(1.5);
                app.mycurStartLoc = blockStartPositionSteps;
            end
            %% Run (3 sec - prep time) timer 
            elapsedForPrep = GetSecs - expTime_start;
            curClock = GetSecs;
            while GetSecs - curClock < (3 - elapsedForPrep)
                drawnow limitrate
                if app.stopGUI == 1
                    errordlg('Experiment stopped', 'Stopped Exp')
                    app.stopGUI = 0;
                    return;
                end
            end

            for n = 1:nTrials
                try
                    trialRow = experimentTrial_Matrix(n,:);
                    myAngle = trialRow.StimDirection;
                    [app.curAngle, flipSpeed] = correctDirection(app.rotation_and_spin_motor, app.curAngle, myAngle); % rorates stimulus to the correct direction - PREP
                    myTrialHistory = printTrialInfo(app, myTrialHistory, n, trialRow, myAngle); 

                    [trialRow, no_motion_flag, moveDir] = runTrial(app, windowPtr, trialRow, flipSpeed, expTime_start);


                    if isActive
                        waitBeforeReturnInstruction = 1; %sec

                        curClock = GetSecs;
                        while GetSecs - curClock < waitBeforeReturnInstruction
                            drawnow limitrate
                            if app.stopGUI == 1
                                errordlg('Experiment stopped', 'Stopped Exp')
                                app.stopGUI = 0;
                                error('Experiment stopped');
                            end
                        end

                        if n < nTrials
                            if  ~no_motion_flag
                                ActiveIti = 1; 
                                textString = sprintf('Move all the way %s. Click when ready', moveDir);
                                DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
                                returnTimeStart = GetSecs;
                                Screen('Flip', windowPtr);
                                waitReadyClick(app,windowPtr);
                                returnTime = GetSecs - returnTimeStart;
                   
                            else
                                ActiveIti = 2; 
                                returnTime = 0; 
                                DrawFormattedText(windowPtr, '', 'center', 'center', [255 255 255]);
                                Screen('Flip', windowPtr)
                                
                            end
                            totalIti = waitBeforeReturnInstruction + returnTime +  ActiveIti ;
                            trialRow.InterTrialInterval = totalIti;
                            experimentTrial_Matrix(n,:) = trialRow;
                            runInterTrialInterval(app, ActiveIti, outName, experimentTrial_Matrix);


                        elseif n == nTrials % on the last trial ask to return all the way to the left
                            totalIti = waitBeforeReturnInstruction;  % only the 1-sec wait
                            trialRow.InterTrialInterval = totalIti;
                            experimentTrial_Matrix(n,:) = trialRow;  % ← write back last trial
                            textString = 'Move all the way left';
                            DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
                            Screen('Flip', windowPtr);
                        end
                    else
                        if n < nTrials
                            waitBeforeReturn = 1;
                            curClock = GetSecs;
                            while GetSecs - curClock < waitBeforeReturn
                                drawnow limitrate
                                if app.stopGUI == 1
                                    errordlg('Experiment stopped', 'Stopped Exp')
                                    app.stopGUI = 0;
                                    error('Experiment stopped');
                                end
                            end

                            returnTimeStart = GetSecs;
                            moveToNextStartPosition(app, experimentTrial_Matrix, n); %takes 0.3 sec + about 1.75 sec
                            returnTime = GetSecs - returnTimeStart;
                            PassiveIti = 2;
                            %% FIgure out how ITI should be for passive, and override them in the behavior file with total iti
                            totalIti = waitBeforeReturn + returnTime + PassiveIti;
                            trialRow.InterTrialInterval = totalIti;
                            experimentTrial_Matrix(n,:) = trialRow;

                            runInterTrialInterval(app, PassiveIti, outName, experimentTrial_Matrix);%- elapsed to account for pauses in moveToNextStartPosition
                        else  % last trial
                            totalIti = 0;
                            trialRow.InterTrialInterval = totalIti;
                            experimentTrial_Matrix(n,:) = trialRow;  % ← write back last trial
                        end
                    end
                   

                    spd = trialRow.MeasuredSpeed_cm_s;
                    if ~isempty(spd) && isfinite(spd)
                        app.MeasuredSpeedHistory(end+1,1) = spd;
                    end

                    updateMeasuredSpeedHistogram(app);
                    drawnow
                    myTrialHistory = printResponseInfo(app, trialRow.Response,myTrialHistory);

                catch ME
                    save(outName, 'experimentTrial_Matrix');
                    disp(getReport(ME, 'extended'));  % print stack to Command Window

                    if ~isempty(ME.stack)
                        s = ME.stack(1);
                        extra = sprintf('\nFile: %s\nFunction: %s\nLine: %d', s.file, s.name, s.line);
                    else
                        extra = '';
                    end

                    msg = sprintf('Block stopped on trial %d:\n%s%s', n, ME.message, extra);
                    errordlg(msg, 'Block Stopped');
    
                    if isActive
                        textString = 'Please, move your arm all the way to the left'; %TEMP - can in theory figure out where to move if at the and of each trail ask to go all the way
   
                        DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
                        Screen('Flip', windowPtr);
                    else
                        %move the stage back to the left if passive
                        lastStagePosition = experimentTrial_Matrix.Arm_mov_StartPosition_arduino(n) + experimentTrial_Matrix.Arm_Mov_Steps_arduino(n);
                        stepsToMoveToLeft = abs(lastStagePosition);
                        cmd = sprintf("M %d\n", stepsToMoveToLeft);
                        writeline(app.LinearStage_motor, cmd);
                        app.mycurLoc = 0;
                    end
                    return
                end

            end
            save(outName, 'experimentTrial_Matrix');
            results = experimentTrial_Matrix;
       
            %  BLOCK END rotate stimulus to center point
            myAngle = app.rotationCtrAngle;
            correctDirection(app.rotation_and_spin_motor, app.curAngle, myAngle);
            app.curAngle = myAngle;

            % move the stage to the left side
            lastStagePosition = experimentTrial_Matrix.Arm_mov_StartPosition_arduino(end) + experimentTrial_Matrix.Arm_Mov_Steps_arduino(end);
            stepsToMoveToLeft = abs(lastStagePosition);
            cmd = sprintf("A %d\n", app.betweenTrialsMotionAcc_steps);
            writeline(app.LinearStage_motor, cmd);
            pause(.1);
            cmd = sprintf("S %d\n", app.betweenTrialsMotionSpeed_steps);
            writeline(app.LinearStage_motor, cmd);
            pause(.1);
            cmd = sprintf("M %d\n", stepsToMoveToLeft);
            writeline(app.LinearStage_motor, cmd);
            app.mycurLoc = 0;

            results = experimentTrial_Matrix;
        end

        function moveToNextStartPosition(app, experimentTrial_Matrix, n)
            start_n = experimentTrial_Matrix.Arm_mov_StartPosition_arduino(n); % (0 or −maxSteps)
            steps_n = experimentTrial_Matrix.Arm_Mov_Steps_arduino(n);
            nextStart = experimentTrial_Matrix.Arm_mov_StartPosition_arduino(n+1);

            endPos_n = start_n + steps_n; % where the stage is located at the end of the trial on 0----- (-maxSteps) axis
            stepsToMove = nextStart - endPos_n;

            cmd = sprintf("A %d\n", app.betweenTrialsMotionAcc_steps);
            writeline(app.LinearStage_motor, cmd);
            pause(.1);
            cmd = sprintf("S %d\n", app.betweenTrialsMotionSpeed_steps);
            writeline(app.LinearStage_motor, cmd);
            pause(.1);
            cmd = sprintf("M %d\n", stepsToMove);
            writeline(app.LinearStage_motor, cmd);
            pause(.1);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            

            clc; close all

            % PsychDefaultSetup(2);
            app.RunButton.Enable = 'off';
            app.LoadMotorsCheckBox.Enable = 'off';
            %app.PauseButton.Enable = 'off';
          %  app.PracticetrialsButton.Enable = 'off';

            screens = Screen('Screens');
            secondMonitor = max(screens);

            [windowPtr, rect] = Screen('OpenWindow', secondMonitor, [0 0 0]);


            Screen('TextSize', windowPtr, 40);
            Screen('TextFont', windowPtr, 'Arial');

            % %----------------
            % stepsStage = app.StepsEditField.Value;
            % if app.RightButton.Value == 1
            %     stepsStage = -1 * stepsStage;
            % else
            % end
            % cmd = sprintf("M %d\n", stepsStage);
            % writeline(app.LinearStage_motor, cmd);
            % %-------------------

            if app.DirectionButton.Value == 1 %run motion direction experiment
                motionDirectionExperiment(app, windowPtr);
            else %run motion speed experiment
            end

            app.RunButton.Enable = 'on';
            app.LoadMotorsCheckBox.Enable = 'on';
        %    app.PauseButton.Enable = 'on';
           % app.PracticetrialsButton.Enable = 'on';

            % Close the window
            Screen('CloseAll');

        end

        % Value changed function: LoadMotorsCheckBox
        function LoadMotorsCheckBoxValueChanged(app, event)
            value = app.LoadMotorsCheckBox.Value;
            
            if value == 1

                try
                    app.rotation_and_spin_motor = serialport(app.rotation_and_spin_motor_comPort, app.rotation_and_spin_motor_Rate);  % Stepper  / Herkulex
                    app.indentationActuator = serialport(app.indentationActuator_comPort, app.indentationActuator_Rate);  % Linear actuator
                    app.LinearStage_motor = serialport(app.LinearStage_motor_comPort, app.LinearStage_motor_Rate);  % Linear stage
                    app.rotaryEncoder = serialport( ...
                        app.rotaryEncoder_comPort, ...
                        app.rotaryEncoder_Rate);
                    
                    configureTerminator(app.rotaryEncoder,"LF");
                    flush(app.rotaryEncoder);
                    pause(0.25);

                    configureTerminator(app.LinearStage_motor, "LF");
                    flush(app.LinearStage_motor);
                    pause(.25);

                    %------------------
                    speedStage = app.SpeedEditField.Value;
                    cmd = sprintf("S %d\n", speedStage);
                    writeline(app.LinearStage_motor, cmd);
                    pause(.25);

                    accStage = app.SpeedEditField.Value;
                    cmd = sprintf("A %d\n", accStage);
                    writeline(app.LinearStage_motor, cmd);
                    pause(.25);
                    %------------------

                    %push the linear actuator to its most retracted position
                    write(app.indentationActuator, sprintf("%d %d\n", 1, app.baseActuatorPos), "string");
                    app.indentZero = app.baseActuatorPos;
                    app.curIndent = app.indentZero;
                    pause(.25);

                    helpdlg('Arduino-controled motors connected', 'Success');

                    app.motorsLoaded = 1;

                catch
                    app.rotaryEncoder = [];
                    app.rotation_and_spin_motor = [];
                    app.indentationActuator = [];
                    app.LinearStage_motor = [];
                    app.motorsLoaded = [];

                    errordlg('Error connecting to Arduino-controled devices', 'Devices not connected');
                end

            else
                app.rotaryEncoder = [];
                app.rotation_and_spin_motor = [];
                app.indentationActuator = [];
                app.LinearStage_motor = [];
                app.motorsLoaded = [];

            end
        end

        % Value changed function: CreateTrialsButton
        function CreateTrialsButtonValueChanged(app, event)
              
            if ~isempty(app.ParticipantsIDEditField.Value)
                myDir = uigetdir;
                if myDir ~= 0                    
                    app.curFolder = myDir;
                    app.participantID = app.ParticipantsIDEditField.Value;

                    if app.DirectionButton.Value == 1
                        taskType = 'Motion_Direction';
                    else
                        taskType = 'Motion_Speed';
                    end
                    %generating trials
                    generate_trials_MotionDirection_with_passive(app.curFolder, app.participantID, taskType);

                else                    
                end

            else
                errordlg('Participant ID missing', 'Error generating trials');
            end

            focus(app.UIFigure);
        end

        % Callback function
        function PracticetrialsButtonValueChanged(app, event)
            value = app.PracticetrialsButton.Value;
            
            if ~isempty(app.rotation_and_spin_motor)

                WaitSecs(1);

                myAngle = app.RotationEditField.Value;
                [myAngle,flipSpeed] = correctDirection(app.rotation_and_spin_motor,app.curAngle,myAngle);

                write(app.rotation_and_spin_motor, sprintf("%d %d %d\n", 3, flipSpeed * 600, 2000), "string");

                startStim = GetSecs;
                indentLoc = app.curIndent;
                write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string");
                buttonPushed = [];
                respTime = [];
                while (GetSecs - startStim) < 2
                    drawnow limitrate
                end
                indentLoc = app.indentZero;
                write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string"); %retracting stimulus

                app.curAngle = myAngle;
            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
        end

        % Value changed function: MoveButton
        function MoveButtonValueChanged(app, event)
                        
            if ~isempty(app.LinearStage_motor)

                %------------------
                speedStage = app.SpeedEditField.Value;
                cmd = sprintf("S %d\n", speedStage);
                writeline(app.LinearStage_motor, cmd);
                pause(.25);

                accStage = app.SpeedEditField.Value;
                cmd = sprintf("A %d\n", accStage);
                writeline(app.LinearStage_motor, cmd);
                pause(.25);

                stepsStage = app.StepsEditField.Value;
                if app.RightButton.Value == 1
                    stepsStage = -1 * stepsStage;
                else
                end
                cmd = sprintf("M %d\n", stepsStage);
                writeline(app.LinearStage_motor, cmd);
                %------------------
                % app.mycurLoc = app.mycurLoc + stepsStage;
                

            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end

        end

        % Value changed function: RotatemotorButton
        function RotatemotorButtonValueChanged(app, event)
            if ~isempty(app.rotation_and_spin_motor)

                newRotation = app.RotationEditField.Value;

                correctDirection_forcalibration(app.rotation_and_spin_motor, newRotation);
                app.curAngle = app.curAngle - newRotation;
                pause(.1);

            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
            
        end

        % Value changed function: Set90Button
        function Set90ButtonValueChanged(app, event)
           
            if ~isempty(app.rotation_and_spin_motor)

                %rotating stimulus to correct direction               
                app.rotationCtrAngle = 90;                
                app.curAngle = app.rotationCtrAngle;
                helpdlg('Rotation value at 90º set', 'Success');


            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
        end

        % Value changed function: MoveUpDownButton
        function MoveUpDownButtonValueChanged(app, event)
            if ~isempty(app.indentationActuator)

                indentSteps = app.IndentEditField.Value;
                indentSteps = indentSteps * (app.maxActuatorPos - app.baseActuatorPos)/app.maxActuatorDisp;
                newLoc = round(app.curIndent + indentSteps);

                %if desired movement is within the range then move the
                %actuator
                if (newLoc >= app.baseActuatorPos) & (newLoc <= app.maxActuatorPos)
                   write(app.indentationActuator, sprintf("%d %d\n", 1, newLoc), "string");
                   app.curIndent = newLoc;
                else
                    errordlg('The movement is beyond the range of the actuator', 'Error moving actuator');
                end
                
                pause(1);
     
            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end


        end

        % Value changed function: MovefullyupButton
        function MovefullyupButtonValueChanged(app, event)
            if ~isempty(app.indentationActuator)

                %move actuator to the base posiution (the least extended
                %pos)
                write(app.indentationActuator, sprintf("%d %d\n", 1, app.baseActuatorPos), "string");
                app.curIndent = app.baseActuatorPos;
                app.indentZero = app.curIndent;
                pause(1);

            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
            
        end

        % Value changed function: SetzeropointButton
        function SetzeropointButtonValueChanged(app, event)
            if ~isempty(app.indentationActuator)

                indentSteps = -2;
                indentSteps = indentSteps * (app.maxActuatorPos - app.baseActuatorPos)/app.maxActuatorDisp;
                newLoc = round(app.curIndent + indentSteps);

                %if desired movement is within the range then move the
                %actuator
                if (newLoc >= app.baseActuatorPos) & (newLoc <= app.maxActuatorPos)
                   write(app.indentationActuator, sprintf("%d %d\n", 1, newLoc), "string");
                   app.indentZero = newLoc;                
                   helpdlg('Zero-based position set', 'Success');
                else
                    errordlg('The movement is beyond the range of the actuator', 'Error moving actuator');
                end

                
                pause(1);
                
            else
                errordlg('Zero point position not set', 'Devices not connected');
            end
            
        end

        % Value changed function: LoadExperimentFileButton
        function LoadExperimentFileButtonValueChanged(app, event)
            [fileToLoad,parDir,indx] = uigetfile();
       

            if indx ~= 0

                try
                    app.fileName = fileToLoad;
                    app.curFolder = parDir;
                    app.fileLoaded = load(fullfile(app.curFolder, app.fileName));
                    app.ExperimentFileNALabel.Text = ['Experiment File: ' app.fileName];
                    if isfield(app.fileLoaded, 'TrialStim_param')
                        app.curPairId = app.fileLoaded.TrialStim_param.PairID(1);
                    end
             
                catch
                    app.fileName = [];
                    app.curFolder = [];
                    app.fileLoaded = [];
                    app.ExperimentFileNALabel.Text = 'Experiment File: N/A';
                end

            else
            end

            focus(app.UIFigure);
            
        end

        % Value changed function: SetstartPosButton
        function SetstartPosButtonValueChanged(app, event)
            if ~isempty(app.rotation_and_spin_motor)
                app.mycurLoc = 0;
                helpdlg('Starting hand position set', 'Success');
            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
            end
            
            
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)

            app.RunButton.Enable = 'on';
            app.LoadMotorsCheckBox.Enable = 'on';
         %   app.PauseButton.Enable = 'on';
           % app.PracticetrialsButton.Enable = 'on';

            app.stopGUI = 1;

        end

        % Value changed function: LinearStageMotorSwitch
        function LinearStageMotorSwitchValueChanged(app, event)
            value = app.LinearStageMotorSwitch.Value;
            if isempty(app.LinearStage_motor)
                uialert(app.UIFigure, 'Linear stage motor is not connected.', 'Motor Control Error');
                return;
            end
        
            try
                if strcmpi(value, 'On')
                    % ON = engaged
                    writeline(app.LinearStage_motor, "E 0");
                else
                    % OFF = free
                    writeline(app.LinearStage_motor, "E 1");
                end
                pause(0.1);
            catch ME
                uialert(app.UIFigure, ME.message, 'Motor Control Error');
            end
        end

        % Button pushed function: TestEncoderButton
        function TestEncoderButtonPushed(app, event)
            flush(app.rotaryEncoder)
        
            disp("Reading encoder...")
        
            tStart = tic;
        
            while toc(tStart) < 5
        
                if app.rotaryEncoder.NumBytesAvailable > 0
        
                    line = readline(app.rotaryEncoder);
        
                    disp(line)
        
                end
            end 
        end

        % Button pushed function: LoadPairedPassiveBlockButton
        function LoadPairedPassiveBlockButtonPushed(app, event)
           
        %     if isempty(app.fileLoaded) || ~isfield(app.fileLoaded, 'TrialStim_param')
        %         uialert(app.UIFigure, 'Load an ACTIVE block first.', 'No block loaded');
        %         return;
        %     end
        % 
        %     app.participantID = app.ParticipantsIDEditField.Value;
        %     if isempty(app.participantID)
        %         uialert(app.UIFigure, 'Participant ID is not set.', 'Missing participant');
        %         return;
        %     end
        % 
        %     pairID = app.fileLoaded.TrialStim_param.PairID(1);
        %     app.curPairId = pairID;
        % 
        %     % Load the behavior data from the active
        %     [activeBehavior, activeBehaviorFile] = loadPairedActiveBehaviorFile(app, pairID);
        % 
        %     ActiveStats = generatePassiveCommands(activeBehavior);
        % 
        %     avgTableFile = fullfile(app.curFolder, sprintf('%d_avg_speeds.csv', pairID));
        %     rowT = struct2table(ActiveStats);  % 1-row table with fields as variables
        % 
        %     % FIX: Convert text fields to cellstr to match CSV reading types
        %     rowT.ParticipantID = cellstr(char(app.participantID));
        %     rowT.PairID        = pairID;
        %     rowT.Timestamp     = cellstr(char(datetime('now','Format','yyyy-MM-dd HH:mm:ss')));
        % 
        %     rowT = rowT(:, {'ParticipantID','PairID','avgFast','avgSlow', ...
        %         'new_speed_fast_steps','new_acc_fast_steps','new_dist_fast_steps', ...
        %         'new_speed_slow_steps','new_acc_slow_steps','new_dist_slow_steps', ...
        %         'nFastInRange','nSlowInRange','pctFastInRange','pctSlowInRange', ...  
        %         'nFastTotal','nSlowTotal','Timestamp'});
        % 
        %     % append/save - new pair id is a new row
        %     if isfile(avgTableFile)
        %         T = readtable(avgTableFile);
        %         idx = string(T.PairID) == string(pairID);
        %         if any(idx)
        %             % Safety check: Clean up any rogue string data types before assigning row
        %             rowT = convertvars(rowT, @isstring, @cellstr);
        %             T(idx,:) = rowT;
        %         else
        %             T = [T; rowT];
        %         end
        %     else
        %         T = rowT;
        %     end
        % 
        %     writetable(T, avgTableFile);
        % 
        %     % Rewrite the passive trials
        %     passiveTrialFile = findPairedPassiveTrialFile(app, pairID);
        %     S = load(passiveTrialFile);
        %     if isfield(S, 'TrialStim_param')
        %         passiveTrials = S.TrialStim_param;
        %     else
        %         error('File does not contain TrialStim_param : %s', passiveTrialFile);
        %     end
        % 
        %     fastMask = passiveTrials.Arm_Mov_Speed_arduino == app.fastSpeedCommanded;
        %     originalSpeedsFast = passiveTrials.Arm_Mov_Speed_arduino(fastMask);
        %     originalStepsFast = passiveTrials.Arm_Mov_Steps_arduino(fastMask);
        % 
        %     passiveTrials.Arm_Mov_Speed(fastMask) = ActiveStats.avgFast;
        %     passiveTrials.Arm_Mov_Speed_arduino(fastMask) = abs(ActiveStats.new_speed_fast_steps) .* sign(originalSpeedsFast);
        %     passiveTrials.Arm_Mov_Acc_arduino(fastMask) = ActiveStats.new_acc_fast_steps;
        %     passiveTrials.Arm_Mov_Steps_arduino(fastMask) = abs(ActiveStats.new_dist_fast_steps) .* sign(originalStepsFast);
        %     passiveTrials.Arm_Mov_StepsAbs_arduino(fastMask) = abs(ActiveStats.new_dist_fast_steps);
        % 
        %     slowMask = passiveTrials.Arm_Mov_Speed_arduino == app.slowSpeedCommanded;
        %     originalSpeedsSlow = passiveTrials.Arm_Mov_Speed_arduino(slowMask);
        %     originalStepsSlow =    passiveTrials.Arm_Mov_Steps_arduino(slowMask);
        % 
        %     passiveTrials.Arm_Mov_Speed(slowMask) = ActiveStats.avgSlow;
        %     passiveTrials.Arm_Mov_Speed_arduino(slowMask) = abs(ActiveStats.new_speed_slow_steps) .* sign(originalSpeedsSlow);
        %     passiveTrials.Arm_Mov_Acc_arduino(slowMask) = ActiveStats.new_acc_slow_steps;
        %     passiveTrials.Arm_Mov_Steps_arduino(slowMask) = abs(ActiveStats.new_dist_slow_steps) .* sign(originalStepsSlow);
        %     passiveTrials.Arm_Mov_StepsAbs_arduino(slowMask) = abs(ActiveStats.new_dist_slow_steps);
        % 
        %     % Rewrite start positions
        %     newMaxStep = max(passiveTrials.Arm_Mov_StepsAbs_arduino);
        %     startAtZeroMask = passiveTrials.Arm_mov_StartPosition_arduino == 0;
        %     startAtOtherSideMask = ~startAtZeroMask;
        %     passiveTrials.Arm_mov_StartPosition_arduino(startAtZeroMask) = 0;
        %     passiveTrials.Arm_mov_StartPosition_arduino(startAtOtherSideMask) = -newMaxStep;
        % 
        %     TrialStim_param = passiveTrials;
        %     save(passiveTrialFile, 'TrialStim_param');
        % 
        %     % Loads the updated passive block
        %     app.fileLoaded = struct('TrialStim_param', TrialStim_param);
        %     [~, baseName, ext] = fileparts(passiveTrialFile);
        %     app.fileName = [baseName ext];
        %     app.ExperimentFileNALabel.Text = ['Experiment File: ' app.fileName];
        % 
        %     helpdlg(sprintf(['Loaded pair %d\nACTIVE behavior:\n%s\n\n' ...
        %         'PASSIVE block updated and ready to run:\n%s'], ...
        %         pairID, activeBehaviorFile, passiveTrialFile), ...
        %         'Paired files loaded');
        % end

          if isempty(app.fileLoaded) || ~isfield(app.fileLoaded, 'TrialStim_param')
                uialert(app.UIFigure, 'Load an ACTIVE block first.', 'No block loaded');
                return;
            end

            app.participantID = app.ParticipantsIDEditField.Value;
            if isempty(app.participantID)
                uialert(app.UIFigure, 'Participant ID is not set.', 'Missing participant');
                return;
            end

            pairID = app.fileLoaded.TrialStim_param.PairID(1);
            app.curPairId = pairID;

            %Load the behavior data from the active
            [activeBehavior, activeBehaviorFile] = loadPairedActiveBehaviorFile(app, pairID);

            ActiveStats = generatePassiveCommands(activeBehavior);

            avgTableFile = fullfile(app.curFolder, sprintf('%d_avg_speeds.csv', pairID));
            rowT = struct2table(ActiveStats);  % 1-row table with fields as variables
            rowT.ParticipantID = string(app.participantID);
            rowT.PairID        = pairID;
            rowT.Timestamp = cellstr(char(datetime('now','Format','yyyy-MM-dd HH:mm:ss')));

            rowT = rowT(:, {'ParticipantID','PairID','avgFast','avgSlow', ...
                'new_speed_fast_steps','new_acc_fast_steps','new_dist_fast_steps', ...
                'new_speed_slow_steps','new_acc_slow_steps','new_dist_slow_steps', ...
                'nFastInRange','nSlowInRange','pctFastInRange','pctSlowInRange', ...  % ← add these
                'nFastTotal','nSlowTotal','Timestamp'});

            % append/save - new pair id is a new row


            if isfile(avgTableFile)
                T = readtable(avgTableFile);
                idx = string(T.PairID) == string(pairID);
                if any(idx)
                    T(idx,:) = rowT;
                else
                    T = [T; rowT];
                end
            else
                T = rowT;
            end

            writetable(T, avgTableFile);

            % Rewrite the passive trials
            passiveTrialFile = findPairedPassiveTrialFile(app, pairID);
            S = load(passiveTrialFile);
            if isfield(S, 'TrialStim_param')
                passiveTrials = S.TrialStim_param;
            else
                error('File does not contain TrialStim_param : %s', passiveTrialFile);
            end

            fastMask = passiveTrials.Arm_Mov_Speed_arduino == app.fastSpeedCommanded;
            originalSpeedsFast = passiveTrials.Arm_Mov_Speed_arduino(fastMask);
            originalStepsFast = passiveTrials.Arm_Mov_Steps_arduino(fastMask);


            passiveTrials.Arm_Mov_Speed(fastMask) = ActiveStats.avgFast;
            passiveTrials.Arm_Mov_Speed_arduino(fastMask) = abs(ActiveStats.new_speed_fast_steps) .* sign(originalSpeedsFast);
            passiveTrials.Arm_Mov_Acc_arduino(fastMask) = ActiveStats.new_acc_fast_steps;
            passiveTrials.Arm_Mov_Steps_arduino(fastMask) = abs(ActiveStats.new_dist_fast_steps) .* sign(originalStepsFast);
            passiveTrials.Arm_Mov_StepsAbs_arduino(fastMask) = abs(ActiveStats.new_dist_fast_steps);


            slowMask = passiveTrials.Arm_Mov_Speed_arduino == app.slowSpeedCommanded;
            originalSpeedsSlow = passiveTrials.Arm_Mov_Speed_arduino(slowMask);
            originalStepsSlow =    passiveTrials.Arm_Mov_Steps_arduino(slowMask);

            passiveTrials.Arm_Mov_Speed(slowMask) = ActiveStats.avgSlow;
            passiveTrials.Arm_Mov_Speed_arduino(slowMask) = abs(ActiveStats.new_speed_slow_steps) .* sign(originalSpeedsSlow);
            passiveTrials.Arm_Mov_Acc_arduino(slowMask) = ActiveStats.new_acc_slow_steps;
            passiveTrials.Arm_Mov_Steps_arduino(slowMask) = abs(ActiveStats.new_dist_slow_steps) .* sign(originalStepsSlow);
            passiveTrials.Arm_Mov_StepsAbs_arduino(slowMask) = abs(ActiveStats.new_dist_slow_steps);


            % Rewrite start positions
            newMaxStep = max(passiveTrials.Arm_Mov_StepsAbs_arduino);
            startAtZeroMask = passiveTrials.Arm_mov_StartPosition_arduino == 0;
            startAtOtherSideMask = ~startAtZeroMask;
            passiveTrials.Arm_mov_StartPosition_arduino(startAtZeroMask) = 0;
            passiveTrials.Arm_mov_StartPosition_arduino(startAtOtherSideMask) = -newMaxStep;


            TrialStim_param = passiveTrials;
            save(passiveTrialFile, 'TrialStim_param');

            %Loads the updated passive block
            app.fileLoaded = struct('TrialStim_param', TrialStim_param);
            [~, baseName, ext] = fileparts(passiveTrialFile);
            app.fileName = [baseName ext];
            app.ExperimentFileNALabel.Text = ['Experiment File: ' app.fileName];

            helpdlg(sprintf(['Loaded pair %d\nACTIVE behavior:\n%s\n\n' ...
                'PASSIVE block updated and ready to run:\n%s'], ...
                pairID, activeBehaviorFile, passiveTrialFile), ...
                'Paired files loaded');
        end


        function passiveTrialFile = findPairedPassiveTrialFile(app, pairID)
            trialsDir = app.curFolder;

            if ~isfolder(trialsDir)
                error('Participant folder not found: %s', trialsDir);
            end

            pattern = sprintf('*pair_%02d*PASSIVE*.mat', pairID);
            files = dir(fullfile(trialsDir, pattern));

            files = files(~[files.isdir]);

            if isempty(files)
                error('No PASSIVE trial file found for PairID %d in %s', pairID, trialsDir);
            elseif numel(files) > 1
                names = strjoin({files.name}, ', ');
                error('Multiple PASSIVE trial files found for PairID %d in %s: %s', ...
                    pairID, trialsDir, names);
            end

            passiveTrialFile = fullfile(files(1).folder, files(1).name);
        end

        function [activeBehavior, activeBehaviorFile] = loadPairedActiveBehaviorFile(app, pairID)
            behaviorDir = fullfile(app.curFolder, [app.participantID '_data']);

            pattern = sprintf('*pair_%02d*ACTIVE*_behavior.mat', pairID);
            files = dir(fullfile(behaviorDir, pattern));

            if isempty(files)
                error('No ACTIVE behavior file found for PairID %d', pairID);
            elseif numel(files) > 1
                error('Multiple ACTIVE behavior files found for PairID %d. Make filename matching more specific.', pairID);
            end
            activeBehaviorFile = fullfile(files(1).folder, files(1).name);
            S = load(activeBehaviorFile);

            if ~isfield(S, 'experimentTrial_Matrix')
                error('File does not contain experimentTrial_Matrix: %s', activeBehaviorFile);
            end
            activeBehavior = S.experimentTrial_Matrix;

        end

        % Callback function
        function fillbehaviorfilefordebuggingButtonPushed(app, event)

            %FAULTY ! DON TUSE
        
            if isempty(app.fileLoaded)
                uialert(app.UIFigure, 'Load an ACTIVE block first.', 'No block loaded');
                return;
            end
        
            if isempty(app.ParticipantsIDEditField.Value)
                uialert(app.UIFigure, 'Participant ID is not set.', 'Missing participant');
                return;
            end
        
            app.participantID = app.ParticipantsIDEditField.Value;
        
            experimentTrial_Matrix = app.fileLoaded.TrialStim_param;
        
            if ~all(experimentTrial_Matrix.IsActive == 1)
                uialert(app.UIFigure, 'Loaded block is not an ACTIVE block.', 'Wrong block type');
                return;
            end
        
            nTrials = size(experimentTrial_Matrix, 1);
        
            for n = 1:nTrials
                experimentTrial_Matrix.TrialStart_time(n) = n;
                experimentTrial_Matrix.Arm_Mov_Onset(n) = n + 0.25;
                experimentTrial_Matrix.StimOnset(n) = n + 0.50;
                experimentTrial_Matrix.Response(n) = 1;
                experimentTrial_Matrix.ReactionTime(n) = 0.6;
        
                if experimentTrial_Matrix.Arm_Mov_Acc_arduino(n) == app.slowSpeedCommanded
                    experimentTrial_Matrix.MeasuredSpeed_cm_s(n) = 1.36;
                elseif experimentTrial_Matrix.Arm_Mov_Acc_arduino(n) == app.fastSpeedCommanded
                    experimentTrial_Matrix.MeasuredSpeed_cm_s(n) = 7.56;
                else
                    experimentTrial_Matrix.MeasuredSpeed_cm_s(n) = 0;
                end
        
                experimentTrial_Matrix.EncoderSamples{n} = [];
            end
        
            [~, outName] = getBehaviorSavePath(app);
            save(outName, 'experimentTrial_Matrix');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: TrainSingleSpeedButton
        function TrainSingleSpeedButtonPushed(app, event)
            viewDistCm = 71;
            screenWidthCm = 47;
            ballDiamDeg = 1.0;
            padCm = 0;

            no_motion_flag = false;
            moveDir = app.SelectDirectionDropDown.Value;
            speedChoice = app.SelectSpeedDropDown.Value;
            if strcmpi(speedChoice, '1')
                speedToTrain = 1;
                speedWords = 'slow';
            elseif strcmpi(speedChoice, '8')
                speedToTrain = 8;
                 speedWords = 'fast';
            else
                error('Unknown speed selection.');
            end

            buttonPushed = [];
            respTime = [];
            encoderSamples = [];

            writeline(app.LinearStage_motor, "E 1"); % sets the motor free
            pause(0.2);
            startTrainingTrial = GetSecs;
            screens = Screen('Screens');
            secondMonitor = max(screens);
            [windowPtr, rect] = Screen('OpenWindow', secondMonitor, [0 0 0]);

            printActiveMotionCue(app, windowPtr, moveDir, speedWords, no_motion_flag);
            [startArmMov, onsetSample] = waitForEncoderMotion(app, app.encoderThreshold_cm);
            if isempty(onsetSample)
                encoderSamples = [];
            else
                encoderSamples = onsetSample;
            end
            armMovOnset = startArmMov - startTrainingTrial;
     
          
            while true
                elapsed = GetSecs - startArmMov;
                drawActiveMovementCountdown(app, windowPtr, speedWords, ExpConfig.StageMotionDur_sec, startArmMov, ...
                    false, speedToTrain, moveDir, screenWidthCm, ballDiamDeg, viewDistCm, padCm)

                newSamples = readAvailableEncoderSamples(app);
                if ~isempty(newSamples)
                    encoderSamples = [encoderSamples; newSamples];
                  
                end
                if elapsed >= app.moveDurSec
                    break;
                end

                drawnow limitrate
                if app.stopGUI == 1
                    app.stopGUI = 0;
                    error('Experiment stopped');
                end
            end

            MeasuredSpeed = computeAverageEncoderVelocity(app, encoderSamples);
            EncoderSamples = {encoderSamples};
            actualMotionTime = GetSecs - startArmMov;

           textString = sprintf('Your speed: %.2f cm/sec, target : %.2f', MeasuredSpeed, speedToTrain);

            DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
            Screen('Flip', windowPtr);
            WaitSecs(4);

            Screen('CloseAll')
            updateCurrentSpeedDisplay(app, MeasuredSpeed);

        end

        function drawActiveMovementCountdown(app, windowPtr, w, moveDurSec, startArmMov, ...
                showResponsePrompt, speedCmPerSec, moveDir, screenWidthCm, ballDiamDeg, ...
                viewDistCm, padCm)

            % elapsed time
            elapsed = GetSecs - startArmMov;
            if elapsed > moveDurSec
                elapsed = moveDurSec;
            elseif elapsed < 0
                elapsed = 0;
            end

            % clear screen
            Screen('FillRect', windowPtr, [0 0 0]);

            % window geometry
            [screenX, screenY] = Screen('WindowSize', windowPtr);
            centerY = screenY / 2;

            % text settings
            Screen('TextFont', windowPtr, 'Arial');
            Screen('TextSize', windowPtr, 28);
            Screen('TextStyle', windowPtr, 0);

            instrY = round(centerY - 140);
            respY  = round(centerY - 60);

            DrawFormattedText(windowPtr, sprintf('Keep moving your arm %s', w), ...
                'center', instrY, [255 255 255]);

            if showResponsePrompt
                DrawFormattedText(windowPtr, 'Left or Right Response', ...
                    'center', respY, [255 255 255]);
            end

            % ----------------------------
            % conversions
            % ----------------------------
            pixPerCm = screenX / screenWidthCm;

            % ball size: degrees -> cm -> pixels
            ballDiamCm = 2 * viewDistCm * tand(ballDiamDeg / 2);
            ballDiamPix = max(6, round(ballDiamCm * pixPerCm));
            ballRadiusPix = ballDiamPix / 2;

            % motion distance
            dxCm = speedCmPerSec * elapsed;
            dxPix = dxCm * pixPerCm;

            totalDxCm = speedCmPerSec * moveDurSec;
            totalDxPix = totalDxCm * pixPerCm;

            % padding corridor
            padPix = padCm * pixPerCm;

            leftBound  = padPix + ballRadiusPix;
            rightBound = screenX - padPix - ballRadiusPix;
            usableWidth = rightBound - leftBound;
            % fprintf('screenX=%g, screenY=%g\n', screenX, screenY);
            % fprintf('screenWidthCm=%g, viewDistCm=%g, ballDiamDeg=%g, padCm=%g\n', ...
            %     screenWidthCm, viewDistCm, ballDiamDeg, padCm);
            % fprintf('pixPerCm=%g\n', pixPerCm);
            % fprintf('ballDiamCm=%g, ballDiamPix=%g\n', ballDiamCm, ballDiamPix);
            % fprintf('speedCmPerSec=%g, moveDurSec=%g\n', speedCmPerSec, moveDurSec);
            % fprintf('totalDxCm=%g, totalDxPix=%g\n', totalDxCm, totalDxPix);
            % fprintf('leftBound=%g, rightBound=%g, usableWidth=%g\n', ...
            %     leftBound, rightBound, usableWidth);

            if totalDxPix > usableWidth
                error('Motion path too long for screen width and chosen padding.');
            end

            % center the full path within the padded corridor
            pathLeft = leftBound + (usableWidth - totalDxPix) / 2;
            pathRight = rightBound - (usableWidth - totalDxPix) / 2;

            if strcmpi(moveDir, 'right')
                ballX = pathLeft + dxPix;
            elseif strcmpi(moveDir, 'left')
                ballX = pathRight - dxPix;
            else
                error('moveDir must be ''left'' or ''right''.');
            end

            % optional clamp for safety
            ballX = max(leftBound, min(rightBound, ballX));

            % vertical location of ball
            ballY = centerY + 40;

            ballRect = [ballX - ballRadiusPix, ballY - ballRadiusPix, ...
                ballX + ballRadiusPix, ballY + ballRadiusPix];

            % draw ball
            Screen('FillOval', windowPtr, [255 255 255], ballRect);

            % optional: draw motion corridor for debugging
            % Screen('FrameRect', windowPtr, [100 100 100], ...
            %     [leftBound, ballY - 40, rightBound, ballY + 40], 1);

            Screen('Flip', windowPtr);

        end
        
      
        function printActiveMotionCue(app, windowPtr, w, w1, no_motion_flag)
            if ~no_motion_flag
                textString = sprintf('Move your arm %s with %s speed', w, w1);
            else
                textString = 'Keep you arm in the same position';
            end
            DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
            Screen('Flip', windowPtr);
            WaitSecs(2);
        end
        
        function [startArmMov, onsetSample] = waitForEncoderMotion(app, threshold_cm)
            startArmMov = NaN;
            onsetSample = [];

            flushRotaryEncoderBuffer(app);
            writeline(app.rotaryEncoder, "ZERO"); %zeroRotaryEncoder(app);
            pause(0.05);

            while isnan(startArmMov)
                samples = readAvailableEncoderSamples(app);

                if ~isempty(samples)
                    idx = find(abs(samples(:,3)) >= threshold_cm, 1, 'first');

                    if ~isempty(idx)
                        startArmMov = GetSecs;
                        onsetSample = samples(idx,:);   % [t_ms count dist_cm]
                        break;
                    end
                end

                drawnow limitrate
                if app.stopGUI == 1
                    app.stopGUI = 0;
                    error('Experiment stopped');
                end
            end
        end

        % Button pushed function: PracticeStimDirectionButton
        function PracticeStimDirectionButtonPushed(app, event)
            %value = app.PracticeStimDirectionButton.Value;
            
            if ~isempty(app.rotation_and_spin_motor)
                WaitSecs(1);
                 stimDurSec = ExpConfig.StimDur_sec;
                currentAngle = app.curAngle; %where the stim is located in circle coordinates 
                goalAngle = app.AngleToTestEditField.Value;

                [app.curAngle, flipSpeed] = correctDirection(app.rotation_and_spin_motor, currentAngle, goalAngle);
                pause(1);


                indentLoc = app.curIndent;
                write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string");
                indentMovedDown = true;

                write(app.rotation_and_spin_motor, sprintf("%d %d %d\n", 3, ...
                    flipSpeed * 600,...
                    2000), "string");
                startStim = GetSecs;
                stimEndTime = startStim + stimDurSec;
                while GetSecs <= stimEndTime
               
                    
                end
                if indentMovedDown %move stim up after rotation is done
                    indentLoc = app.indentZero;
                    write(app.indentationActuator, sprintf("%d %d\n", 1, indentLoc), "string");
                end
            else
                errordlg('Connect the Arduino-controled devices', 'Devices not connected');
                % Add warning if the 90 is not set 
            end
        end

        % Button pushed function: RunTrainingTrialsButton
        function RunTrainingTrialsButtonPushed(app, event)
            nTrainTrials = 100;
            minTrials = 10;
            viewDistCm = 71;
            screenWidthCm = 47;
            ballDiamDeg = 1.0;
            padCm = 0;
            myTrialHistory = {};
            allPerfSpeeds = [];
            startArmMov = NaN; 
            app.TrialhistoryTextArea.Value = "";

            % fast_upper_speed_threshold = ExpConfig.max_speed_cm;
            fast_upper_speed_threshold = ExpConfig.fast_target * 1.2;
            fast_lower_speed_threshold = ExpConfig.fast_target * 0.8;
            slow_upper_speed_threshold = ExpConfig.slow_target * 1.2;
            slow_lower_speed_threshold = ExpConfig.slow_target * 0.8;
            initHist(app)
            
            no_motion_flag = false;
            moveDir = app.SelectDirectionDropDown.Value;
            speedChoice = app.SelectSpeedDropDown.Value;
                 writeline(app.LinearStage_motor, "E 1"); % sets the motor free
                    pause(0.2);
       
            if strcmpi(speedChoice, 'slow')
                speedToTrain = ExpConfig.slow_target;
                speedWords = 'slow';
            elseif strcmpi(speedChoice, 'fast')
                speedToTrain = ExpConfig.fast_target;
                speedWords = 'fast';
            else
                error('Unknown speed selection.');
            end
            if strcmp(speedWords, 'fast')
                lower_limit = fast_lower_speed_threshold;
                upper_limit = fast_upper_speed_threshold;
            elseif strcmp(speedWords, 'slow')
                lower_limit = slow_lower_speed_threshold;
                upper_limit = slow_upper_speed_threshold;
            else
                lower_limit = -Inf; % Fallback if speedWords doesn't match
                upper_limit = Inf;
            end
            screens = Screen('Screens');
            secondMonitor = max(screens);

            [windowPtr, rect] = Screen('OpenWindow', secondMonitor, [0 0 0]);
            threshold_cm = ExpConfig.encoderThreshold_cm;
         
            % text settings
            Screen('TextFont', windowPtr, 'Arial');
            Screen('TextSize', windowPtr, 28);
            Screen('TextStyle', windowPtr, 0);

            DrawFormattedText(windowPtr, 'Starting Training, click when ready', 'center', 'center', [255 255 255]);
            Screen('Flip', windowPtr);
         
            pause(1);

            goAhead = false;
            while goAhead == false
                [~, ~, buttons] = GetMouse;
                if any(buttons)
                    goAhead = true;
                     break;
                end
            end
            pause(1);


            for i=1:nTrainTrials
                try
                    if mod(i, 2) == 0
                        moveDir = "left";
                    else
                        moveDir = "right";
                    end

                    encoderSamples = [];
                    startTrainingTrial = GetSecs;
                    startArmMov = NaN;
                    onsetSample = [];
                    flushRotaryEncoderBuffer(app);
                    writeline(app.rotaryEncoder, "ZERO");
                    pause(0.05);
                    if isempty(onsetSample)
                        encoderSamples = [];
                    else
                        encoderSamples = onsetSample;
                    end
                    %% Display instruction during movement, capture speed
                    while true
                    
                        drawInstructionCue(app, windowPtr, speedWords, ExpConfig.StageMotionDur_sec, ...
                            false, speedToTrain, moveDir, screenWidthCm, ballDiamDeg, viewDistCm, padCm,startTrainingTrial,moveDir );
                        % read encoder every frame
                        newSamples = readAvailableEncoderSamples(app);
                        if ~isempty(newSamples)
                            encoderSamples = [encoderSamples; newSamples];
                            % detect onset once
                            if isnan(startArmMov)
                                idx = find(abs(newSamples(:,3)) >= threshold_cm, 1, 'first');
                                if ~isempty(idx)
                                    startArmMov = GetSecs;
                                    onsetSample = newSamples(idx,:);
                                end
                            end
                        end
                        % after movement onset, stop after duration
                        if ~isnan(startArmMov)
                            elapsed = GetSecs - startArmMov;
                            if elapsed >= app.moveDurSec
                                break;
                            end
                        end
                        drawnow limitrate
                        if app.stopGUI == 1
                            app.stopGUI = 0;
                            error('Experiment stopped');
                        end
                    end
                
                    %% Log measured speed

                    MeasuredSpeed = computeAverageEncoderVelocity(app, encoderSamples);
                    allPerfSpeeds = [allPerfSpeeds MeasuredSpeed];
                    avgPerfSpeed = mean(allPerfSpeeds, 'omitnan');
                    in_bounds_mask = (allPerfSpeeds >= lower_limit) & (allPerfSpeeds <= upper_limit);
                    count_within = sum(in_bounds_mask);

                    total_trials = numel(allPerfSpeeds);
                    if count_within == 0
                        percentage_within = 0;
                    else
                        percentage_within = (count_within / total_trials) * 100;
                    end

                    myTrialHistory = printSpeedStat(app, i,  myTrialHistory, MeasuredSpeed, avgPerfSpeed , percentage_within, moveDir);
                    textString = sprintf('Your speed: %.2f cm/sec, target : %.2f. Click when ready', MeasuredSpeed, speedToTrain);
                    DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
                    Screen('Flip', windowPtr);
                    %wait a sec then go on to the next trial
                    pause(2);
                    updateCurrentSpeedDisplay(app, MeasuredSpeed);
                    app.MeasuredSpeedHistory(end+1,1) = MeasuredSpeed;
                    if percentage_within > 50 && i>minTrials
                        break
                    end

                    %waiting for button press to go to the next trial
                    goAhead = false;
                    while goAhead == false
                        [~, ~, buttons] = GetMouse;
                        if any(buttons)
                            goAhead = true;
                            break;
                        end
                    end
                    pause(1);


                    % actualMotionTime = GetSecs - startArmMov;

                    % if strcmp(moveDir, 'right')
                    %     returnDir = 'left';
                    %     targetDistanceCm = 0;
                    %    lower_dist_bound = 0;
                    %    upper_dist_bound = 0.8;
                    % else
                    %     returnDir = 'right';
                    %     targetDistanceCm = ExpConfig.LinStage_range_cm;
                    %     % lower_dist_bound = targetDistanceCm * 0.80;
                    %     % upper_dist_bound = targetDistanceCm
                    % end


                    %currentDistance = encoderSamples(end, 3);
                    % fprintf('Dist at end of motion: %.2f cm   \r', currentDistance);


                    % ITIencoderSamples = [];
                    % while true
                    %     flushRotaryEncoderBuffer(app);
                    %     newSamples = readAvailableEncoderSamples(app);
                    %
                    %     if ~isempty(newSamples)
                    %         ITIencoderSamples = [ITIencoderSamples; newSamples];
                    %          %currentDistance = ITIencoderSamples(end, 3);
                    %          %fprintf('Current Position NEW : %.2f cm   \r', currentDistance);
                    %     end
                    %
                    %     if ~isempty(ITIencoderSamples)
                    %         currentDistance = ITIencoderSamples(end, 3);
                    %         fprintf('Current Position: %.2f cm   \r', currentDistance);
                    %     end
                    %     if ~isnan(currentDistance) && (currentDistance >= lower_dist_bound) && (currentDistance <= upper_dist_bound)
                    %         fprintf('Position at break Position: %.2f cm   \r', currentDistance);
                    %         break;
                    %     end
                    %
                    %     drawnow limitrate
                    %     if app.stopGUI == 1
                    %         app.stopGUI = 0;
                    %         error('Experiment stopped');
                    %     end
                    % end
                catch ME
                    % 1. Print full diagnostic trace to the command window
                       Screen('CloseAll');

                    disp(getReport(ME, 'extended'));
                    

                    % 2. Extract specific location of the error from the stack trace
                    if ~isempty(ME.stack)
                        s = ME.stack(1);
                        extra = sprintf('\n\nFile: %s\nFunction: %s\nLine: %d', s.file, s.name, s.line);
                    else
                        extra = '';
                    end

                    msg = [ME.message extra];

                    % 3. Safely close any hanging Psychtoolbox windows so the screens don't stay frozen
                 
                    % 4. Display the popup error dialog box to the user
                    errordlg(msg, 'Block Stopped');

                    return
                end


            end


            textString = sprintf('Your avg speed: %.2f cm/sec, target : %.2f', avgPerfSpeed, speedToTrain);
            DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
            Screen('Flip', windowPtr);
            WaitSecs(4);
            Screen('CloseAll')


        end

        function drawInstructionCue(app, windowPtr, w, moveDurSec, ...
                showResponsePrompt, speedCmPerSec, moveDir, screenWidthCm, ballDiamDeg, ...
                viewDistCm, padCm, startCue, directionToMove)
            elapsed = mod(GetSecs - startCue, moveDurSec);
            if elapsed > moveDurSec
                elapsed = moveDurSec;
            elseif elapsed < 0
                elapsed = 0;
            end
            textString = sprintf('Move your arm %s with %s speed', directionToMove , w);

            % clear screen
            Screen('FillRect', windowPtr, [0 0 0]);
            % window geometry
            [screenX, screenY] = Screen('WindowSize', windowPtr);
            centerY = screenY / 2;

            % text settings
            Screen('TextFont', windowPtr, 'Arial');
            Screen('TextSize', windowPtr, 28);
            Screen('TextStyle', windowPtr, 0);

            instrY = round(centerY - 140);
            respY  = round(centerY - 60);

            DrawFormattedText(windowPtr,textString , ...
                'center', instrY, [255 255 255]);

            % ----------------------------
            % conversions
            % ----------------------------
            pixPerCm = screenX / screenWidthCm;

            % ball size: degrees -> cm -> pixels
            ballDiamCm = 2 * viewDistCm * tand(ballDiamDeg / 2);
            ballDiamPix = max(6, round(ballDiamCm * pixPerCm));
            ballRadiusPix = ballDiamPix / 2;

            % motion distance
            dxCm = speedCmPerSec * elapsed;
            dxPix = dxCm * pixPerCm;

            totalDxCm = speedCmPerSec * moveDurSec;
            totalDxPix = totalDxCm * pixPerCm;

            % padding corridor
            padPix = padCm * pixPerCm;

            leftBound  = padPix + ballRadiusPix;
            rightBound = screenX - padPix - ballRadiusPix;
            usableWidth = rightBound - leftBound;
 
            if totalDxPix > usableWidth
                error('Motion path too long for screen width and chosen padding.');
            end

            % center the full path within the padded corridor
            pathLeft = leftBound + (usableWidth - totalDxPix) / 2;
            pathRight = rightBound - (usableWidth - totalDxPix) / 2;

            if strcmpi(moveDir, 'right')
                ballX = pathLeft + dxPix;
            elseif strcmpi(moveDir, 'left')
                ballX = pathRight - dxPix;
            else
                error('moveDir must be ''left'' or ''right''.');
            end

            % optional clamp for safety
            ballX = max(leftBound, min(rightBound, ballX));

            % vertical location of ball
            ballY = centerY + 40;

            ballRect = [ballX - ballRadiusPix, ballY - ballRadiusPix, ...
                ballX + ballRadiusPix, ballY + ballRadiusPix];

            % draw ball
            Screen('FillOval', windowPtr, [255 0 0], ballRect);
            Screen('Flip', windowPtr);

        end

        function drawActiveMovementCountdown2(app, windowPtr, w, moveDurSec, startArmMov, ...
                showResponsePrompt, speedCmPerSec, moveDir, screenWidthCm, ballDiamDeg, ...
                viewDistCm, padCm)

            % elapsed time
            elapsed = GetSecs - startArmMov;
            if elapsed > moveDurSec
                elapsed = moveDurSec;
            elseif elapsed < 0
                elapsed = 0;
            end

            % clear screen
            Screen('FillRect', windowPtr, [0 0 0]);

            % window geometry
            [screenX, screenY] = Screen('WindowSize', windowPtr);
            centerY = screenY / 2;

            % text settings
            Screen('TextFont', windowPtr, 'Arial');
            Screen('TextSize', windowPtr, 28);
            Screen('TextStyle', windowPtr, 0);

            instrY = round(centerY - 140);
            respY  = round(centerY - 60);

            DrawFormattedText(windowPtr, sprintf('Keep moving your arm %s', w), ...
                'center', instrY, [255 255 255]);

            if showResponsePrompt
                DrawFormattedText(windowPtr, 'Left or Right Response', ...
                    'center', respY, [255 255 255]);
            end

            % ----------------------------
            % conversions
            % ----------------------------
            pixPerCm = screenX / screenWidthCm;

            % ball size: degrees -> cm -> pixels
            ballDiamCm = 2 * viewDistCm * tand(ballDiamDeg / 2);
            ballDiamPix = max(6, round(ballDiamCm * pixPerCm));
            ballRadiusPix = ballDiamPix / 2;

            % motion distance
            dxCm = speedCmPerSec * elapsed;
            dxPix = dxCm * pixPerCm;

            totalDxCm = speedCmPerSec * moveDurSec;
            totalDxPix = totalDxCm * pixPerCm;

            % padding corridor
            padPix = padCm * pixPerCm;

            leftBound  = padPix + ballRadiusPix;
            rightBound = screenX - padPix - ballRadiusPix;
            usableWidth = rightBound - leftBound;
            % fprintf('screenX=%g, screenY=%g\n', screenX, screenY);
            % fprintf('screenWidthCm=%g, viewDistCm=%g, ballDiamDeg=%g, padCm=%g\n', ...
            %     screenWidthCm, viewDistCm, ballDiamDeg, padCm);
            % fprintf('pixPerCm=%g\n', pixPerCm);
            % fprintf('ballDiamCm=%g, ballDiamPix=%g\n', ballDiamCm, ballDiamPix);
            % fprintf('speedCmPerSec=%g, moveDurSec=%g\n', speedCmPerSec, moveDurSec);
            % fprintf('totalDxCm=%g, totalDxPix=%g\n', totalDxCm, totalDxPix);
            % fprintf('leftBound=%g, rightBound=%g, usableWidth=%g\n', ...
            %     leftBound, rightBound, usableWidth);

            if totalDxPix > usableWidth
                error('Motion path too long for screen width and chosen padding.');
            end

            % center the full path within the padded corridor
            pathLeft = leftBound + (usableWidth - totalDxPix) / 2;
            pathRight = rightBound - (usableWidth - totalDxPix) / 2;

            if strcmpi(moveDir, 'right')
                ballX = pathLeft + dxPix;
            elseif strcmpi(moveDir, 'left')
                ballX = pathRight - dxPix;
            else
                error('moveDir must be ''left'' or ''right''.');
            end

            % optional clamp for safety
            ballX = max(leftBound, min(rightBound, ballX));

            % vertical location of ball
            ballY = centerY + 40;

            ballRect = [ballX - ballRadiusPix, ballY - ballRadiusPix, ...
                ballX + ballRadiusPix, ballY + ballRadiusPix];

            % draw ball
            Screen('FillOval', windowPtr, [255 0 0], ballRect);

            % optional: draw motion corridor for debugging
            % Screen('FrameRect', windowPtr, [100 100 100], ...
            %     [leftBound, ballY - 40, rightBound, ballY + 40], 1);

            Screen('Flip', windowPtr);

        end


        function printActiveMotionCue2(app, windowPtr, w, w1, no_motion_flag)
            if ~no_motion_flag
                textString = sprintf('Move your arm %s with %s speed', w, w1);
            else
                textString = 'Keep you arm in the same position';
            end
            DrawFormattedText(windowPtr, textString, 'center', 'center', [255 255 255]);
            Screen('Flip', windowPtr);
            WaitSecs(2);
        end

        function myTrialHistory = printSpeedStat(app, n,  myTrialHistory, measuredSpeed, avgSpeed , percentWithin, movDir)
            myText = sprintf('#%d %s measured speed %.2f, cur avg speed %.2f, percent within: %.1f%%', ...
                n, movDir, measuredSpeed, avgSpeed, percentWithin);
           % myText = ['#' num2str(n) ' measured speed ' num2str(measuredSpeed) ' , cur avg speed ' num2str(avgSpeed) ', percent within: ' ...
              %  num2str(percentWithin) '%'];
            myTrialHistory{end+1,1} = myText;
            app.TrialhistoryTextArea.Value = myTrialHistory;
            scroll(app.TrialhistoryTextArea, 'bottom');
            drawnow
        end


        function [startArmMov, onsetSample] = waitForEncoderMotion1(app, threshold_cm)
            startArmMov = NaN;
            onsetSample = [];

            flushRotaryEncoderBuffer(app);
            writeline(app.rotaryEncoder, "ZERO"); %zeroRotaryEncoder(app);
            pause(0.05);

            while isnan(startArmMov)
                samples = readAvailableEncoderSamples(app);

                if ~isempty(samples)
                    idx = find(abs(samples(:,3)) >= threshold_cm, 1, 'first');

                    if ~isempty(idx)
                        startArmMov = GetSecs;
                        onsetSample = samples(idx,:);   % [t_ms count dist_cm]
                        break;
                    end
                end

                drawnow limitrate
                if app.stopGUI == 1
                    app.stopGUI = 0;
                    error('Experiment stopped');
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MovestagePanel = uipanel(app.UIFigure);
            app.MovestagePanel.Title = 'Move stage';
            app.SpeedEditFieldLabel = uilabel(app.MovestagePanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.MovestagePanel, 'numeric');
            app.SpeedEditField.Limits = [0 2600];
            app.SpeedEditField.Value = 2600;
            app.StepsEditFieldLabel = uilabel(app.MovestagePanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.MovestagePanel, 'numeric');
            app.StepsEditField.Limits = [0 500];
            app.StepsEditField.Value = 300;
            app.DirectionButtonGroup = uibuttongroup(app.MovestagePanel);
            app.DirectionButtonGroup.Title = 'Direction';
            app.LeftButton = uiradiobutton(app.DirectionButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.DirectionButtonGroup);
            app.RightButton.Text = 'Right';
            app.MoveButton = uibutton(app.MovestagePanel, 'state');
            app.MoveButton.ValueChangedFcn = createCallbackFcn(app, @MoveButtonValueChanged, true);
            app.MoveButton.Text = 'Move';
            app.SetstartPosButton = uibutton(app.MovestagePanel, 'state');
            app.SetstartPosButton.ValueChangedFcn = createCallbackFcn(app, @SetstartPosButtonValueChanged, true);
            app.SetstartPosButton.Text = 'Set start Pos.';
            app.ParticipantsIDEditFieldLabel = uilabel(app.UIFigure);
            app.ParticipantsIDEditFieldLabel.Text = 'Participants ID #';
            app.ParticipantsIDEditField = uieditfield(app.UIFigure, 'text');
            app.CentermotorsPanel = uipanel(app.UIFigure);
            app.CentermotorsPanel.Title = 'Center motors';
            app.RotationEditFieldLabel = uilabel(app.CentermotorsPanel);
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.RotationEditField = uieditfield(app.CentermotorsPanel, 'numeric');
            app.RotationEditField.Limits = [-180 180];
            app.RotatemotorButton = uibutton(app.CentermotorsPanel, 'state');
            app.RotatemotorButton.ValueChangedFcn = createCallbackFcn(app, @RotatemotorButtonValueChanged, true);
            app.RotatemotorButton.Text = 'Rotate motor';
            app.MoveUpDownButton = uibutton(app.CentermotorsPanel, 'state');
            app.MoveUpDownButton.ValueChangedFcn = createCallbackFcn(app, @MoveUpDownButtonValueChanged, true);
            app.MoveUpDownButton.Text = 'Move Up/Down';
            app.IndentEditFieldLabel = uilabel(app.CentermotorsPanel);
            app.IndentEditFieldLabel.Text = 'Indent';
            app.IndentEditField = uieditfield(app.CentermotorsPanel, 'numeric');
            app.IndentEditField.Limits = [-26 26];
            app.Set90Button = uibutton(app.CentermotorsPanel, 'state');
            app.Set90Button.ValueChangedFcn = createCallbackFcn(app, @Set90ButtonValueChanged, true);
            app.Set90Button.Text = 'Set 90º';
            app.SetzeropointButton = uibutton(app.CentermotorsPanel, 'state');
            app.SetzeropointButton.ValueChangedFcn = createCallbackFcn(app, @SetzeropointButtonValueChanged, true);
            app.SetzeropointButton.Text = 'Set zero point';
            app.MovefullyupButton = uibutton(app.CentermotorsPanel, 'state');
            app.MovefullyupButton.ValueChangedFcn = createCallbackFcn(app, @MovefullyupButtonValueChanged, true);
            app.MovefullyupButton.Text = 'Move fully up';
            app.GeneratetrialsButtonGroup = uibuttongroup(app.UIFigure);
            app.GeneratetrialsButtonGroup.Title = 'Generate trials';
            app.DirectionButton = uiradiobutton(app.GeneratetrialsButtonGroup);
            app.DirectionButton.Text = 'Direction';
            app.DirectionButton.Value = true;
            app.SpeedButton = uiradiobutton(app.GeneratetrialsButtonGroup);
            app.SpeedButton.Text = 'Speed';
            app.ExperimentFileNALabel = uilabel(app.UIFigure);
            app.ExperimentFileNALabel.Text = 'Experiment File: N/A';
            app.TrialhistoryTextAreaLabel = uilabel(app.UIFigure);
            app.TrialhistoryTextAreaLabel.Text = 'Trial history';
            app.TrialhistoryTextArea = uitextarea(app.UIFigure);
            app.CreateTrialsButton = uibutton(app.UIFigure, 'state');
            app.CreateTrialsButton.ValueChangedFcn = createCallbackFcn(app, @CreateTrialsButtonValueChanged, true);
            app.CreateTrialsButton.Text = 'Create Trials';
            app.MotorControlPanel = uipanel(app.UIFigure);
            app.MotorControlPanel.Title = 'Motor Control';
            app.LoadMotorsCheckBox = uicheckbox(app.MotorControlPanel);
            app.LoadMotorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadMotorsCheckBoxValueChanged, true);
            app.LoadMotorsCheckBox.Text = '  Load Motors';
            app.TestEncoderButton = uibutton(app.MotorControlPanel, 'push');
            app.TestEncoderButton.ButtonPushedFcn = createCallbackFcn(app, @TestEncoderButtonPushed, true);
            app.TestEncoderButton.Text = 'Test Encoder';
            app.LinearStageMotorSwitchLabel = uilabel(app.MotorControlPanel);
            app.LinearStageMotorSwitchLabel.Text = 'Linear Stage Motor ';
            app.LinearStageMotorSwitch = uiswitch(app.MotorControlPanel, 'slider');
            app.LinearStageMotorSwitch.ValueChangedFcn = createCallbackFcn(app, @LinearStageMotorSwitchValueChanged, true);
            app.ExperimentControlPanel = uipanel(app.UIFigure);
            app.ExperimentControlPanel.Title = 'Experiment Control';
            app.RunButton = uibutton(app.ExperimentControlPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.ExperimentControlPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.LoadExperimentFileButton = uibutton(app.ExperimentControlPanel, 'state');
            app.LoadExperimentFileButton.ValueChangedFcn = createCallbackFcn(app, @LoadExperimentFileButtonValueChanged, true);
            app.LoadExperimentFileButton.Text = 'Load Experiment File';
            app.LoadPairedPassiveBlockButton = uibutton(app.ExperimentControlPanel, 'push');
            app.LoadPairedPassiveBlockButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPairedPassiveBlockButtonPushed, true);
            app.LoadPairedPassiveBlockButton.Text = 'Load Paired Passive Block';
            app.LinearStageSpeedTrainingPanel = uipanel(app.UIFigure);
            app.LinearStageSpeedTrainingPanel.Title = 'Linear Stage Speed Training';
            app.TrainSingleSpeedButton = uibutton(app.LinearStageSpeedTrainingPanel, 'push');
            app.TrainSingleSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @TrainSingleSpeedButtonPushed, true);
            app.TrainSingleSpeedButton.Text = 'Train Single Speed';
            app.SelectSpeedDropDownLabel = uilabel(app.LinearStageSpeedTrainingPanel);
            app.SelectSpeedDropDownLabel.Text = 'Select Speed ';
            app.SelectSpeedDropDown = uidropdown(app.LinearStageSpeedTrainingPanel);
            app.SelectSpeedDropDown.Items = {'fast', 'slow'};
            app.SelectSpeedDropDown.Value = 'fast';
            app.SelectDirectionDropDownLabel = uilabel(app.LinearStageSpeedTrainingPanel);
            app.SelectDirectionDropDownLabel.Text = 'Select Direction';
            app.SelectDirectionDropDown = uidropdown(app.LinearStageSpeedTrainingPanel);
            app.SelectDirectionDropDown.Items = {'right', 'left'};
            app.SelectDirectionDropDown.Value = 'right';
            app.RunTrainingTrialsButton = uibutton(app.LinearStageSpeedTrainingPanel, 'push');
            app.RunTrainingTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrainingTrialsButtonPushed, true);
            app.RunTrainingTrialsButton.Text = 'Run Training Trials';
            app.StimulusDirectionPracticePanel = uipanel(app.UIFigure);
            app.StimulusDirectionPracticePanel.Title = 'Stimulus Direction Practice';
            app.AngleToTestEditFieldLabel = uilabel(app.StimulusDirectionPracticePanel);
            app.AngleToTestEditFieldLabel.Text = 'Angle To Test';
            app.AngleToTestEditField = uieditfield(app.StimulusDirectionPracticePanel, 'numeric');
            app.PracticeStimDirectionButton = uibutton(app.StimulusDirectionPracticePanel, 'push');
            app.PracticeStimDirectionButton.ButtonPushedFcn = createCallbackFcn(app, @PracticeStimDirectionButtonPushed, true);
            app.PracticeStimDirectionButton.Text = 'Practice Stim Direction ';
            app.ArmSpeedMonitorPanel = uipanel(app.UIFigure);
            app.ArmSpeedMonitorPanel.Title = 'Arm Speed Monitor';
            app.UIAxes = uiaxes(app.ArmSpeedMonitorPanel);
            title(app.UIAxes, 'Arm movements')
            xlabel(app.UIAxes, 'Movement Speed (cm/s)')
            ylabel(app.UIAxes, 'Counts')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CurrentArmMovementNALabel = uilabel(app.ArmSpeedMonitorPanel);
            app.CurrentArmMovementNALabel.Text = 'Current Arm Movement: N/A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = with_encoder_motion_control_experiment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end